classdef MoriagariViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MoriagariViewerUIFigure   matlab.ui.Figure
        MoriagariMeterGaugeLabel  matlab.ui.control.Label
        MoriagariMeterGauge       matlab.ui.control.SemicircularGauge
        OuenMessage               matlab.ui.control.Label
        TypesPerSecLabel          matlab.ui.control.Label
        typessecLabel             matlab.ui.control.Label
        TypesPerSecEditField      matlab.ui.control.NumericEditField
        TextEditField             matlab.ui.control.EditField
    end

    
    properties (Access = private)
        UpdateTimer; % TimerのObject
        TimerStartFlag; % Timer開始フラグ
        TypeNum; % タイプ数カウンタ
        PastTypeNum; % Description
        
        %音声データ保存領域
        VoiceLv1;
        VoiceLv2;
        VoiceLv3;
        VoiceLv4;
        VoiceLv5;
        VoiceLv6;
        VoiceLv7;
        %ここまで音声データ保存領域
    end
    
    methods (Access = private)
        
        function mytimer_func(app, obj, event)

            %インゲージバー更新---------------------------------
            %今のタイプ数がPastTypeNumに対して同値以上であれば
            if app.TypeNum >= app.PastTypeNum 
                %タイプ数をメータに追加
                NewMerterValue = app.TypeNum + app.MoriagariMeterGauge.Value;
            else
                %今のタイプ数がPastTypeNum以下であれば現在のメータ値を0.95倍にする。
                NewMerterValue = app.MoriagariMeterGauge.Value*0.95;
            end
            
            %メータ値の上限、下限処理
            if NewMerterValue > 100.0
                NewMerterValue = 100.0;
            end
            
            if NewMerterValue < 0.0
                NewMerterValue = 0.0;
            end
            
            %メータ値更新
            app.MoriagariMeterGauge.Value = NewMerterValue;
            %------------------------------------------------
            
            %ユーザへのメッセージ更新---------------------------
            if NewMerterValue < 10
                app.OuenMessage.Text = "ここ↓になんでもいいから文字打ったらやる気が出るよ";
                app.OuenMessage.FontSize = 18;
                app.OuenMessage.FontWeight = 'normal';
                app.OuenMessage.FontColor = 'black';
                play(app.VoiceLv1);
                
            elseif NewMerterValue < 30
                app.OuenMessage.Text = "もう少し頑張ったらやる気でるよ！知らんけど！";
                app.OuenMessage.FontSize = 18;
                app.OuenMessage.FontWeight = 'normal';
                app.OuenMessage.FontColor = '#0072BD';
                play(app.VoiceLv2);
                
            elseif NewMerterValue < 50
                app.OuenMessage.Text = "いけるいける！もうちょっと頑張ろう！";
                app.OuenMessage.FontSize = 20;
                app.OuenMessage.FontWeight = 'normal';
                app.OuenMessage.FontColor = '#D95319';
                play(app.VoiceLv3);
                
            elseif NewMerterValue < 70
                app.OuenMessage.Text = "すごいすごい！本当にやる気出てるんじゃない！？";
                app.OuenMessage.FontSize = 22;
                app.OuenMessage.FontWeight = 'bold';
                app.OuenMessage.FontColor = '#D95319';
                play(app.VoiceLv4);
                
            elseif NewMerterValue < 80
                app.OuenMessage.Text = "やる気出すぎでしょ。この勢い止まらないね！";
                app.OuenMessage.FontSize = 26;
                app.OuenMessage.FontWeight = 'bold';
                app.OuenMessage.FontColor = '#D95319';
                play(app.VoiceLv5);
                
            elseif NewMerterValue < 90
                app.OuenMessage.Text = "神の領域だね！やる気MAX！！"; 
                app.OuenMessage.FontSize = 30;
                app.OuenMessage.FontWeight = 'bold';
                app.OuenMessage.FontColor = '#7E2F8E';
                play(app.VoiceLv6);
            else 
                app.OuenMessage.Text = "やる気のボーナスタイムや！"; 
                app.OuenMessage.FontSize = 34;
                app.OuenMessage.FontWeight = 'bold';
                app.OuenMessage.FontColor = '#FF0000';
                play(app.VoiceLv7);
                
            end
             %------------------------------------------------
            
            %TypePerSecが0でないならPastYtpeNumを更新
            if app.TypeNum > 0
                app.PastTypeNum = app.TypeNum;
            end
            
            %TypePerSec更新
            app.TypesPerSecEditField.Value = app.TypeNum;
            
            %TypeNum初期化
            app.TypeNum = 0.0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             %タイマオブジェクト作成 
             app.UpdateTimer = timer('Period', 1.0,...
              'ExecutionMode', 'fixedRate', ...
              'TasksToExecute', Inf, ... 
              'TimerFcn', @app.mytimer_func);
             %パラメータ初期化
             app.TypeNum = 0.0;
             app.PastTypeNum = 0.0;
             app.MoriagariMeterGauge.Value = 0.0;
             
             
             %音声データの読み込み
             load('voicedata.mat');
             
             app.VoiceLv1 = audioplayer(Level1, fs);
             app.VoiceLv2 = audioplayer(Level2, fs);
             app.VoiceLv3 = audioplayer(Level3, fs);
             app.VoiceLv4 = audioplayer(Level4, fs);
             app.VoiceLv5 = audioplayer(Level5, fs);
             app.VoiceLv6 = audioplayer(Level6, fs);
             app.VoiceLv7 = audioplayer(Level7, fs);
             
             
             %タイマ開始
             start(app.UpdateTimer);
        end

        % Callback function: MoriagariViewerUIFigure, TextEditField
        function UIFigureKeyPress(app, event)
           app.TypeNum = app.TypeNum + 1.0;
        end

        % Close request function: MoriagariViewerUIFigure
        function MoriagariViewerUIFigureCloseRequest(app, event)
            stop(app.UpdateTimer)
            delete(app.UpdateTimer)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MoriagariViewerUIFigure = uifigure('Visible', 'off');
            app.MoriagariViewerUIFigure.Name = 'MoriagariViewer';
            app.MoriagariViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MoriagariViewerUIFigureCloseRequest, true);
            app.MoriagariViewerUIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.MoriagariMeterGaugeLabel = uilabel(app.MoriagariViewerUIFigure);
            app.MoriagariMeterGaugeLabel.Text = 'Moriagari Meter';
            app.MoriagariMeterGauge = uigauge(app.MoriagariViewerUIFigure, 'semicircular');
            app.OuenMessage = uilabel(app.MoriagariViewerUIFigure);
            app.OuenMessage.Text = 'ここ↓になんでもいいから文字打ったらやる気が出るよ';
            app.TypesPerSecLabel = uilabel(app.MoriagariViewerUIFigure);
            app.TypesPerSecLabel.Text = 'TypesPerSec';
            app.typessecLabel = uilabel(app.MoriagariViewerUIFigure);
            app.typessecLabel.Text = 'types/sec';
            app.TypesPerSecEditField = uieditfield(app.MoriagariViewerUIFigure, 'numeric');
            app.TextEditField = uieditfield(app.MoriagariViewerUIFigure, 'text');
            app.TextEditField.ValueChangingFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            % Show the figure after all components are created
            app.MoriagariViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MoriagariViewer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MoriagariViewerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.MoriagariViewerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MoriagariViewerUIFigure)
        end
    end
end